classdef EnvironmentEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EmissionObjectsLabel      matlab.ui.control.Label
        BenignObjectsLabel        matlab.ui.control.Label
        EmissionTable             matlab.ui.control.Table
        PointTypeDropDown         matlab.ui.control.DropDown
        PointTypeDropDownLabel    matlab.ui.control.Label
        SelectPointDropDown       matlab.ui.control.DropDown
        SelectPointDropDownLabel  matlab.ui.control.Label
        AddPointButton            matlab.ui.control.Button
        EditPointButton           matlab.ui.control.Button
        BenignTable               matlab.ui.control.Table
        ExitButton                matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        DeletePointButton         matlab.ui.control.Button
        PointMap                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainApp % Description
        environment %class that has all environment info
        %window for making objects
        objectMaker
    end
    methods (Access = public)
        function addPoint(app, selectedType, params)
            % Determine the class type and properties to prompt
            if strcmp(selectedType, 'Benign Object')
                object = BenignObject(params);
                app.environment.benign_objects(end + 1) = object;
            elseif strcmp(selectedType, 'Emission Object')
                object = EmissionObject(params);
                app.environment.emission_objects(end + 1) = object;
            end
            app.updateApp();
        end
    end
    methods (Access = private)

        function updateApp(app)
            % repopulates the table
            app.populateTable();
            app.updateDropDown();
            %app.populateGraph();
        end
        function populateTable(app)
            app.populateBenignTable();
            app.populateEmissionTable();
        end
        function populateBenignTable(app)
            % Initialize an empty cell array to store table data
            tableData = {};
            
            % Initialize a counter for point numbering
            pointNumber = 1;
            
            % Get benign objects from the environment
            for i = 1:length(app.environment.benign_objects)
                benignObj = app.environment.benign_objects(i);
                % Convert Position and Velocity arrays to strings
                positionStr = mat2str(benignObj.position);  % Convert position to string
                velocityStr = mat2str(benignObj.velocity);  % Convert velocity to string
                
                % Store data in the format: {Number, Type, Position, Velocity}
                tableData{pointNumber, 1} = pointNumber;              % Number
                tableData{pointNumber, 2} = positionStr;              % Position (as string)
                tableData{pointNumber, 3} = velocityStr;              % Velocity (as string)
                pointNumber = pointNumber + 1;
            end
            
            % Update the PointsTable with the collected data
            app.BenignTable.Data = tableData;
        end
        function populateEmissionTable(app)
                        % Initialize an empty cell array to store table data
            tableData = {};
            
            % Initialize a counter for point numbering
            pointNumber = 1;
            
            % Get emission objects from the environment
            for i = 1:length(app.environment.emission_objects)
                emissionObj = app.environment.emission_objects(i);
                % Convert Position and Velocity arrays to strings
                positionStr = mat2str(emissionObj.position);          % Convert position to string
                velocityStr = mat2str(emissionObj.velocity);          % Convert velocity to string
                
                % Store data in the format: {Number, Type, Position, Velocity}
                tableData{pointNumber, 1} = pointNumber;              % Number
                tableData{pointNumber, 2} = positionStr;              % Position (as string)
                tableData{pointNumber, 3} = velocityStr;              % Velocity (as string)
                pointNumber = pointNumber + 1;
            end
            
            % Update the PointsTable with the collected data
            app.EmissionTable.Data = tableData;
        end

    function confirmAddPoint(dlg, app, selectedType, propNames, inputFields)
        % Gather input values and create the point
        params = struct();
        for i = 1:length(propNames)
            params.(propNames{i}) = str2num(inputFields{i}.String); % Convert to number if numeric
            if isempty(params.(propNames{i}))
                params.(propNames{i}) = inputFields{i}.String; % Keep as string if not numeric
            end
        end
        
        % Create and add the point to the environment
        if strcmp(selectedType, 'Benign Object')
            newObj = BenignObject(params);
            app.environment.benign_objects(end + 1) = newObj;
            app.populateBenignTable(); % Refresh BenignTable
        elseif strcmp(selectedType, 'Emission Object')
            newObj = EmissionObject(params);
            app.environment.emission_objects(end + 1) = newObj;
            app.populateEmissionTable(); % Refresh EmissionTable
        end
        
        % Close the dialog
        delete(dlg);
    end

    function updateDropDown(app, selectedType)
      if strcmp(selectedType, 'Benign Object')
        % Assuming benignTable contains numeric points in the first column
        pointList = app.BenignTable.Data(:, 1);
      else
        % Assuming emissionTable contains numeric points in the first column
        pointList = app.EmissionTable.Data(:, 1);
      end
   %transpose this thang
    pointList = pointList';  
    pointListStr = cellfun(@num2str, pointList, 'UniformOutput', false);
    % Convert pointList to a cell array of strings for the dropdown items
    app.SelectPointDropDown.Items = pointListStr;
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, environment)
               app.mainApp = mainApp;
               app.environment = environment;
               app.populateTable();
               selectValue = app.PointTypeDropDown.Value;
               app.updateDropDown(selectValue);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Enable the button in main app, if the app is
            % still open
            if isvalid(app.mainApp)
                app.mainApp.EditEnvironmentButton.Enable = "on";
            end
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: AddPointButton
        function AddPointButtonPushed(app, event)
            % Get the selected point type
            selectedType = app.PointTypeDropDown.Value;
            
            % Determine the class type and properties to prompt
            if strcmp(selectedType, 'Benign Object')
                propNames = properties(app.environment.benign_objects);
            elseif strcmp(selectedType, 'Emission Object')
                propNames = properties(app.environment.emission_objects);
            else
                return;
            end

            % Open the options dialog and pass inputs
            radarParams = app.mainApp.returnPrimaryRadar();
            app.objectMaker = ObjectMaker(app, selectedType, propNames, radarParams);
            
        end

        % Value changed function: PointTypeDropDown
        function PointTypeDropDownValueChanged(app, event)
            selectedType = app.PointTypeDropDown.Value;
            app.updateDropDown(selectedType); 
        end

        % Button pushed function: DeletePointButton
        function DeletePointButtonPushed(app, event)
            % Get the selected point type and point number
            selectedType = app.PointTypeDropDown.Value;
            selectedPoint = str2double(app.SelectPointDropDown.Value);
            
            % Perform deletion based on the selected type
            if strcmp(selectedType, 'Benign Object')
                if selectedPoint <= length(app.environment.benign_objects)
                    % Delete the benign object from the environment
                    app.environment.benign_objects(selectedPoint) = [];
                end
            elseif strcmp(selectedType, 'Emission Object')
                if selectedPoint <= length(app.environment.emission_objects)
                    % Delete the emission object from the environment
                    app.environment.emission_objects(selectedPoint) = [];
                end
            end
            
            % Update the app to reflect changes
            app.updateApp();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            updateEnvironment(app.mainApp, Environment);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PointMap = uiaxes(app.UIFigure);
            title(app.PointMap, 'Title')
            xlabel(app.PointMap, 'X')
            ylabel(app.PointMap, 'Y')
            zlabel(app.PointMap, 'Z')
            app.DeletePointButton = uibutton(app.UIFigure, 'push');
            app.DeletePointButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePointButtonPushed, true);
            app.DeletePointButton.Text = 'Delete Point';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.BenignTable = uitable(app.UIFigure);
            app.BenignTable.ColumnName = {'Num'; 'Position'; 'Velocity'};
            app.BenignTable.RowName = {};
            app.BenignTable.ColumnEditable = [false false true true false false];
            app.EditPointButton = uibutton(app.UIFigure, 'push');
            app.EditPointButton.Text = 'Edit Point';
            app.AddPointButton = uibutton(app.UIFigure, 'push');
            app.AddPointButton.ButtonPushedFcn = createCallbackFcn(app, @AddPointButtonPushed, true);
            app.AddPointButton.Text = 'Add Point';
            app.SelectPointDropDownLabel = uilabel(app.UIFigure);
            app.SelectPointDropDownLabel.Text = 'Select Point';
            app.SelectPointDropDown = uidropdown(app.UIFigure);
            app.PointTypeDropDownLabel = uilabel(app.UIFigure);
            app.PointTypeDropDownLabel.Text = 'Point Type';
            app.PointTypeDropDown = uidropdown(app.UIFigure);
            app.PointTypeDropDown.Items = {'Benign Object', 'Emission Object'};
            app.PointTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PointTypeDropDownValueChanged, true);
            app.PointTypeDropDown.Value = 'Benign Object';
            app.EmissionTable = uitable(app.UIFigure);
            app.EmissionTable.ColumnName = {'Num'; 'Position'; 'Velocity'};
            app.EmissionTable.RowName = {};
            app.BenignObjectsLabel = uilabel(app.UIFigure);
            app.BenignObjectsLabel.Text = 'Benign Objects';
            app.EmissionObjectsLabel = uilabel(app.UIFigure);
            app.EmissionObjectsLabel.Text = 'Emission Objects';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EnvironmentEditor(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end