classdef FMCWApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ofFramesEditField           matlab.ui.control.NumericEditField
        ofFramesEditFieldLabel      matlab.ui.control.Label
        detectedFalseBehaviorLabel  matlab.ui.control.Label
        ofSweepsEditField           matlab.ui.control.NumericEditField
        ofSweepsEditFieldLabel      matlab.ui.control.Label
        LoadDetectionButton         matlab.ui.control.Button
        DetectionEvaluationLabel    matlab.ui.control.Label
        ScenerioGenerationLabel     matlab.ui.control.Label
        EvaluateDetectionButton     matlab.ui.control.Button
        DesignDetectionButton       matlab.ui.control.Button
        NoConfigFileSetLabel        matlab.ui.control.Label
        LoadConfigButton            matlab.ui.control.Button
        OpenConfigButton            matlab.ui.control.Button
        ResetScenerioButton         matlab.ui.control.Button
        SaveScenerioButton          matlab.ui.control.Button
        GenerateScenerioButton      matlab.ui.control.StateButton
        EditRadarButton             matlab.ui.control.StateButton
        EditEnvironmentButton       matlab.ui.control.Button
        Results                     matlab.ui.container.Panel
        VidPanel                    matlab.ui.container.Panel
        forwardFrame                matlab.ui.control.Button
        play                        matlab.ui.control.Button
        FrameSpinner                matlab.ui.control.Spinner
        FrameSpinnerLabel           matlab.ui.control.Label
        backFrame                   matlab.ui.control.Button
        SaveGraphsButton            matlab.ui.control.Button
        ClearButton                 matlab.ui.control.Button
        SwitchtoVelocityButton      matlab.ui.control.StateButton
        SaveDataButton              matlab.ui.control.Button
        ViewSpectrumAnalyzerButton  matlab.ui.control.Button
        XvsPowerMap                 matlab.ui.control.UIAxes
        HeatMap                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % instance of FMCWSim class
        radarSim; 
        %run data
        simulationComplete;
        xr;
        fftFrames;
        %currently opened file
        loadedFile;
        %other windows
        radarEditor;
        environmentEditor;
        detectedFalseBehavior = nan;
        isPlaying = false;
    end
    
    methods (Access = public)
        function updateRadar(app, params)
            % Store inputs as properties
            app.radarSim.radar = app.radarSim.radar.setRadar(params);
            % Re-enable the radar Options button
            app.EditRadarButton.Enable = "on";
        end
        function updateEnvironment(app, environment)
            % Store inputs as properties
            app.radarSim.environment = environment;
            % Re-enable the env Options button
            app.EditEnvironmentButton.Enable = "on";
        end
        function params = returnPrimaryRadar(app)
            params = app.radarSim.radar.saveRadar();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('Backend');
            addpath('Config_Files');
            addpath('Supporting_Apps');
            app.radarSim = FMCWSim('Config_Files/default.mat');
            app.simulationComplete = false();
        end

        % Callback function
        function HeatMapButtonDown(app, event)
            
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            % Prompt user to select a .mat file
            [file, path] = uigetfile('*.mat', 'Select Configuration File');
        
            % If user did not cancel
            if ischar(file)
                % Load the .mat file and display its contents
                app.loadedFile = file; 
                config_file = fullfile(path, file);
                app.radarSim = FMCWSim(config_file);
                app.NoConfigFileSetLabel.Text = string(app.loadedFile) + " Loaded";
            end
        end

        % Value changed function: GenerateScenerioButton
        function GenerateScenerioButtonValueChanged(app, event)
            if isempty(app.radarSim)
                uialert(app.UIFigure, 'Please load a configuration first.', 'Error');
                return;
            end
        
            %added heat map
            if(app.ofSweepsEditField.Value <= 0)
                uialert(app.UIFigure, "# of sweeps must be greater than 0")
                return
            end
            if(app.ofFramesEditField.Value <= 0)
                uialert(app.UIFigure, "# of frames must be greater than 0")
                return
            end    
            Nsweep = app.ofSweepsEditField.Value; % You can allow the user to input this value
            Nframes = app.ofFramesEditField.Value;
            % Create a loading screen
            progressDlg = uiprogressdlg(app.UIFigure, ...
                'Title', 'Simulating...', ...
                'Message', 'Please wait while the simulation is running...', ...
                'Indeterminate', 'on');
        
            try
                % Simulate the radar and store the results
                if(Nframes == 1)
                    app.xr = app.radarSim.simulate(Nsweep);
                    app.VidPanel.Visible = 'off';
                    app.SwitchtoVelocityButton.Visible = 'on';
                else
                    xr_frames = app.radarSim.simulateFrames(Nframes, Nsweep);
                    app.fftFrames = app.radarSim.runFFTFrames(xr_frames);
                    app.FrameSpinner.Limits = [1, Nframes];
                    app.VidPanel.Visible = 'on';
                    app.SwitchtoVelocityButton.Visible = 'off';
                end
            catch ME
                % Close the progress dialog if an error occurs
                close(progressDlg);
                rethrow(ME);
            end
        
            % Clear previous plots on the axes
            cla(app.HeatMap);
            
            app.simulationComplete = true();
            % Plotting the range-Doppler response on the existing HeatMap axes
            if(Nframes == 1)
                app.radarSim.plotRangeDoppler(app.xr, app.HeatMap);
                app.radarSim.plotRangeVsPower(app.XvsPowerMap);
            else
                app.radarSim.plotRangeDopplerFrame(app.HeatMap, app.fftFrames, 1, Nframes);
                app.radarSim.plotRangeVsPowerFrame(app.XvsPowerMap, app.fftFrames, 1, Nframes);

            end
            %add range power graph

        end

        % Button pushed function: ResetScenerioButton
        function ResetScenerioButtonPushed(app, event)
            app.radarSim = FMCWSim('Config_Files/default.mat');
            app.NoConfigFileSetLabel.Text = 'No Config File Set'; 
        end

        % Button pushed function: SaveScenerioButton
        function SaveScenerioButtonPushed(app, event)
            %check to see if scenerio is ready
            if isempty(app.radarSim)
                uialert(app.UIFigure, 'Please load a configuration first.', 'Error');
                return;
            end

            %get file name
            prompt = {"Enter config file name:"};
            dlgtitle = 'Load Config Input';
            fieldsize = [1 50];  % Size of the input field
            definput = {'radar_config.mat'};  % Default file name

            save_file = inputdlg(prompt, dlgtitle, fieldsize, definput);
            save_file = save_file{1};
            if ~endsWith(save_file, '.mat')
                save_file = strcat(save_file, '.mat');
            end
            primary = app.radarSim.radar.saveRadar;

            saveEnvironment = app.radarSim.environment.saveEnvironment;
            % Convert benign_objects and emission_objects (which are cells) to structs
            benign_objects = saveEnvironment.benign_objects;
            emission_objects = saveEnvironment.emission_objects;
            floor = saveEnvironment.floor;
            save(save_file, 'primary', 'emission_objects', 'benign_objects', 'floor');
        end

        % Button pushed function: OpenConfigButton
        function OpenConfigButtonPushed(app, event)
    % Prompt user to select a .mat file
        [file, path] = uigetfile('*.mat', 'Select Configuration File');
        
        % If user did not cancel
        if ischar(file)
            % Load the .mat file and display its contents
            config_file = fullfile(path, file);
            data = load(config_file);
            
            % Create a new figure window to display the contents
            fig = uifigure('Name', 'MAT File Contents', 'Position', [100, 100, 400, 300]);
            uitextarea(fig, 'Value', evalc('disp(data)'), 'Position', [10, 10, 380, 280]);
        end

        
        end

        % Value changed function: EditRadarButton
        function EditRadarButtonValueChanged(app, event)
            % Get radar information
            radar = app.radarSim.radar;
            
            % Open the options dialog and pass inputs
            app.radarEditor = RadarEditor(app,radar);
        end

        % Close request function: UIFigure
        function CloseRequest(app, event)
            delete(app.radarEditor)
            delete(app.environmentEditor)
            delete(app)
            
        end

        % Button pushed function: EditEnvironmentButton
        function EditEnvironmentButtonPushed(app, event)
            % Get environment information
            environment = app.radarSim.environment;
            
            % Open the options dialog and pass inputs
            app.environmentEditor = EnvironmentEditor(app,environment);
        end

        % Button pushed function: ViewSpectrumAnalyzerButton
        function ViewSpectrumAnalyzerButtonPushed(app, event)
            if(not(app.simulationComplete))
                uialert(app.UIFigure, 'Please generate a run first.', 'Error');
                return;
            end
            app.radarSim.openSpectrumAnalyzer();
        end

        % Value changed function: SwitchtoVelocityButton
        function SwitchtoVelocityButtonValueChanged(app, event)
            if(not(app.simulationComplete))
                uialert(app.UIFigure, 'Please generate a run first.', 'Error');
                return;
            end
            if strcmp(app.SwitchtoVelocityButton.Text, 'Switch to Velocity')
                % Plot the Velocity vs Power graph and update button text
                app.radarSim.plotVelocityVsPower(app.XvsPowerMap);
                app.SwitchtoVelocityButton.Text = 'Switch to Range';
            else
                % Plot the Distance vs Power graph and update button text
                app.radarSim.plotRangeVsPower(app.XvsPowerMap);
                app.SwitchtoVelocityButton.Text = 'Switch to Velocity';
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            if(not(app.simulationComplete))
                uialert(app.UIFigure, 'Please generate a run first.', 'Error');
                return;
            end
            cla(app.HeatMap);
            cla(app.XvsPowerMap);
            app.simulationComplete = false();
            app.xr = [];

        end

        % Button pushed function: SaveGraphsButton
        function SaveGraphsButtonPushed(app, event)
            if(not(app.simulationComplete))
                uialert(app.UIFigure, 'Please generate a run first.', 'Error');
                return;
            end
            if(strcmp(app.VidPanel.Visible,'off'))
                % Create an input dialog to get the filename
                prompt = {'Enter the filename for saving graphs:'};
                dlgTitle = 'Save Graphs as PNG';
                dims = [1 50];
                defaultInput = {'GraphFilename'};
                answer = inputdlg(prompt, dlgTitle, dims, defaultInput);
                
                % Check if the user provided a filename
                if ~isempty(answer)
                    % Get the filename from the input dialog
                    filename = answer{1};
                    
                    % Define full paths for each graph file
                    heatMapFile = fullfile(pwd, [filename, '_HeatMap.png']);
                    xvsPowerMapFile = fullfile(pwd, [filename, '_XvsPowerMap.png']);
                    
                    % Save each graph as PNG
                    exportgraphics(app.HeatMap, heatMapFile, 'Resolution', 300);      % Saves HeatMap as PNG
                    exportgraphics(app.XvsPowerMap, xvsPowerMapFile, 'Resolution', 300);  % Saves XvsPowerMap as PNG
                    
                    % Optional: Display a message confirming the save
                    msgbox('Graphs saved successfully!', 'Success');
                else
                    % Optional: Display a message if the user cancels
                    msgbox('Save operation canceled.', 'Cancel');
                end
            
            else
                                % Create an input dialog to get the filename
                prompt = {'Enter the filename for saving graphs:'};
                dlgTitle = 'Save Graphs as mp4';
                dims = [1 50];
                defaultInput = {'GraphFilename'};
                answer = inputdlg(prompt, dlgTitle, dims, defaultInput);
                
                % Check if the user provided a filename
                if ~isempty(answer)
                    % Get the filename from the input dialog
                    filename = answer{1};
                    
                    % Define full paths for each graph file
                    heatMapFile = fullfile(pwd, [filename, '_HeatMap.mp4']);
                    xvsPowerFile = fullfile(pwd, [filename, '_XvsPowerMap.mp4']);
                    
                    app.radarSim.saveRangeDopplerVideo(app.fftFrames, heatMapFile);
                    app.radarSim.saveRangeVsPowerVideo(app.fftFrames, xvsPowerFile);
                    % Optional: Display a message confirming the save
                    msgbox('Graphs saved successfully!', 'Success');
                else
                    % Optional: Display a message if the user cancels
                    msgbox('Save operation canceled.', 'Cancel');
                end
            end

        end

        % Button pushed function: DesignDetectionButton
        function DesignDetectionButtonPushed(app, event)
            open('Backend/detectFalseBehavior');
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            if(not(app.simulationComplete))
                uialert(app.UIFigure, 'Please generate a run first.', 'Error');
                return;
            end
            % Create an input dialog to get the filename
            prompt = {'Enter the filename for saving data:'};
            dlgTitle = 'Save fft data';
            dims = [1 50];
            defaultInput = {'GraphFilename'};
            answer = inputdlg(prompt, dlgTitle, dims, defaultInput);


            % Check if the user provided a filename
            if ~isempty(answer)
                % Get the filename from the input dialog
                filename = answer{1};
                if ~endsWith(filename, '.mat')
                    filename = strcat(filename, '.mat');
                end
                % Prepare the FFT data to save
                fftData.resp = app.fftFrames.resp_frames;
                fftData.rng_grid = app.fftFrames.rng_grid;
                fftData.dop_grid = app.fftFrames.dop_grid;
        
                % Try saving the data to a .mat file
                try
                    save(filename, '-struct', 'fftData');
                    % Display a message confirming the save
                    uialert(app.UIFigure, 'Data saved successfully!', 'Success');
                catch
                    % Display an error message if the file cannot be saved
                    uialert(app.UIFigure, 'Error saving the file. Check permissions or filename.', 'Save Error');
                end
            else
                msgbox('Save operation canceled.', 'Cancel');
            end
        end

        % Button pushed function: EvaluateDetectionButton
        function EvaluateDetectionButtonPushed(app, event)
            if(not(app.simulationComplete))
                uialert(app.UIFigure, 'Please generate a run first.', 'Error');
                return;
            end
            
            app.detectedFalseBehavior = detectFalseBehavior(app.fftFrames);
            if app.detectedFalseBehavior(1)
                app.detectedFalseBehaviorLabel.Text = "False behavior detected";
            else
                app.detectedFalseBehaviorLabel.Text = "False behavior not detected";
            end
            app.radarSim.saveDetectionVid(app.fftFrames, 'detection_vid.mp4', app.detectedFalseBehavior)
            app.detectedFalseBehaviorLabel.Visible = "on";
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            if(not(app.simulationComplete))
                uialert(app.UIFigure, 'Please generate a run first.', 'Error');
                return;
            end
            frame = app.FrameSpinner.Value;
            Nframes = size(app.fftFrames.resp_frames, 2);
            app.radarSim.plotRangeDopplerFrame(app.HeatMap, app.fftFrames, frame, Nframes);
            app.radarSim.plotRangeVsPowerFrame(app.XvsPowerMap, app.fftFrames, frame, Nframes);
        end

        % Button pushed function: play
        function playButtonPushed(app, event)
            if(not(app.simulationComplete))
                uialert(app.UIFigure, 'Please generate a run first.', 'Error');
                return;
            end
            % Check the current state of the button
            if strcmp(app.play.Text, 'Play')
                % If the button is in the "Play" state, start playing
                app.play.Text = 'Pause'; % Change the button text to "Pause"
                app.isPlaying = true; % Set a flag indicating the playing state
                
                Nframes = size(app.fftFrames.resp_frames, 2);
                frame = 1;
                
                % Use a while loop to allow for pausing
                while frame <= Nframes && app.isPlaying
                    app.FrameSpinner.Value = frame;
                    app.radarSim.plotRangeDopplerFrame(app.HeatMap, app.fftFrames, frame, Nframes);
                    app.radarSim.plotRangeVsPowerFrame(app.XvsPowerMap, app.fftFrames, frame, Nframes);
                    pause(0.1);
                    frame = frame + 1;
                end
                
                % Reset the button to "Play" when playback completes
                if frame > Nframes
                    app.play.Text = 'Play';
                    app.isPlaying = false;
                end
            else
                % If the button is in the "Pause" state, pause the playback
                app.play.Text = 'Play'; % Change the button text to "Play"
                app.isPlaying = false; % Set the flag to stop playback
            end
        end

        % Button pushed function: forwardFrame
        function forwardFrameButtonPushed(app, event)
            if(not(app.simulationComplete))
                uialert(app.UIFigure, 'Please generate a run first.', 'Error');
                return;
            end
            
            cur_frame = app.FrameSpinner.Value;
            if(cur_frame+1 > size(app.fftFrames.resp_frames, 2))
                return;
            else
                app.FrameSpinner.Value = cur_frame+1;
                app.FrameSpinnerValueChanged();
            end
        end

        % Button pushed function: backFrame
        function backFrameButtonPushed(app, event)
            if(not(app.simulationComplete))
                uialert(app.UIFigure, 'Please generate a run first.', 'Error');
                return;
            end
            cur_frame = app.FrameSpinner.Value;
            if(cur_frame-1 < 1)
                return;
            else
                app.FrameSpinner.Value = cur_frame-1;
                app.FrameSpinnerValueChanged();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.Results = uipanel(app.UIFigure);
            app.Results.Title = 'Outputs';
            app.HeatMap = uiaxes(app.Results);
            title(app.HeatMap, 'Range Speed Response')
            xlabel(app.HeatMap, 'X')
            ylabel(app.HeatMap, 'Y')
            zlabel(app.HeatMap, 'Z')
            app.XvsPowerMap = uiaxes(app.Results);
            title(app.XvsPowerMap, 'Range vs Power Graph')
            xlabel(app.XvsPowerMap, 'X')
            ylabel(app.XvsPowerMap, 'Y')
            zlabel(app.XvsPowerMap, 'Z')
            app.ViewSpectrumAnalyzerButton = uibutton(app.Results, 'push');
            app.ViewSpectrumAnalyzerButton.ButtonPushedFcn = createCallbackFcn(app, @ViewSpectrumAnalyzerButtonPushed, true);
            app.ViewSpectrumAnalyzerButton.Text = 'View Spectrum Analyzer';
            app.SaveDataButton = uibutton(app.Results, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.SwitchtoVelocityButton = uibutton(app.Results, 'state');
            app.SwitchtoVelocityButton.ValueChangedFcn = createCallbackFcn(app, @SwitchtoVelocityButtonValueChanged, true);
            app.SwitchtoVelocityButton.Text = 'Switch to Velocity';
            app.ClearButton = uibutton(app.Results, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SaveGraphsButton = uibutton(app.Results, 'push');
            app.SaveGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphsButtonPushed, true);
            app.SaveGraphsButton.Text = 'Save Graphs';
            app.VidPanel = uipanel(app.Results);
            app.VidPanel.Visible = 'off';
            app.backFrame = uibutton(app.VidPanel, 'push');
            app.backFrame.ButtonPushedFcn = createCallbackFcn(app, @backFrameButtonPushed, true);
            app.backFrame.Text = '<<';
            app.FrameSpinnerLabel = uilabel(app.VidPanel);
            app.FrameSpinnerLabel.Text = 'Frame';
            app.FrameSpinner = uispinner(app.VidPanel);
            app.FrameSpinner.Limits = [1 1];
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSpinner.Value = 1;
            app.play = uibutton(app.VidPanel, 'push');
            app.play.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.play.Text = 'Play';
            app.forwardFrame = uibutton(app.VidPanel, 'push');
            app.forwardFrame.ButtonPushedFcn = createCallbackFcn(app, @forwardFrameButtonPushed, true);
            app.forwardFrame.Text = '>>';
            app.EditEnvironmentButton = uibutton(app.UIFigure, 'push');
            app.EditEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @EditEnvironmentButtonPushed, true);
            app.EditEnvironmentButton.Text = 'Edit Environment';
            app.EditRadarButton = uibutton(app.UIFigure, 'state');
            app.EditRadarButton.ValueChangedFcn = createCallbackFcn(app, @EditRadarButtonValueChanged, true);
            app.EditRadarButton.Text = 'Edit Radar';
            app.GenerateScenerioButton = uibutton(app.UIFigure, 'state');
            app.GenerateScenerioButton.ValueChangedFcn = createCallbackFcn(app, @GenerateScenerioButtonValueChanged, true);
            app.GenerateScenerioButton.Text = 'Generate Scenerio';
            app.SaveScenerioButton = uibutton(app.UIFigure, 'push');
            app.SaveScenerioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScenerioButtonPushed, true);
            app.SaveScenerioButton.Text = 'Save Scenerio';
            app.ResetScenerioButton = uibutton(app.UIFigure, 'push');
            app.ResetScenerioButton.ButtonPushedFcn = createCallbackFcn(app, @ResetScenerioButtonPushed, true);
            app.ResetScenerioButton.Text = 'Reset Scenerio';
            app.OpenConfigButton = uibutton(app.UIFigure, 'push');
            app.OpenConfigButton.ButtonPushedFcn = createCallbackFcn(app, @OpenConfigButtonPushed, true);
            app.OpenConfigButton.Text = 'Open Config';
            app.LoadConfigButton = uibutton(app.UIFigure, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.NoConfigFileSetLabel = uilabel(app.UIFigure);
            app.NoConfigFileSetLabel.Text = 'No Config File Set';
            app.DesignDetectionButton = uibutton(app.UIFigure, 'push');
            app.DesignDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @DesignDetectionButtonPushed, true);
            app.DesignDetectionButton.Text = 'Design Detection';
            app.EvaluateDetectionButton = uibutton(app.UIFigure, 'push');
            app.EvaluateDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateDetectionButtonPushed, true);
            app.EvaluateDetectionButton.Text = 'Evaluate Detection';
            app.ScenerioGenerationLabel = uilabel(app.UIFigure);
            app.ScenerioGenerationLabel.Text = 'Scenerio Generation';
            app.DetectionEvaluationLabel = uilabel(app.UIFigure);
            app.DetectionEvaluationLabel.Text = 'Detection Evaluation';
            app.LoadDetectionButton = uibutton(app.UIFigure, 'push');
            app.LoadDetectionButton.Text = 'Load Detection';
            app.ofSweepsEditFieldLabel = uilabel(app.UIFigure);
            app.ofSweepsEditFieldLabel.Text = '# of Sweeps';
            app.ofSweepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofSweepsEditField.Value = 64;
            app.detectedFalseBehaviorLabel = uilabel(app.UIFigure);
            app.detectedFalseBehaviorLabel.Visible = 'off';
            app.ofFramesEditFieldLabel = uilabel(app.UIFigure);
            app.ofFramesEditFieldLabel.Text = '# of Frames';
            app.ofFramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofFramesEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FMCWApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end