classdef EnvironmentEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        mLabel                    matlab.ui.control.Label
        floorBox                  matlab.ui.control.NumericEditField
        IncludeFloorCheckBox      matlab.ui.control.CheckBox
        EmissionObjectsLabel      matlab.ui.control.Label
        BenignObjectsLabel        matlab.ui.control.Label
        EmissionTable             matlab.ui.control.Table
        PointTypeDropDown         matlab.ui.control.DropDown
        PointTypeDropDownLabel    matlab.ui.control.Label
        SelectPointDropDown       matlab.ui.control.DropDown
        SelectPointDropDownLabel  matlab.ui.control.Label
        AddPointButton            matlab.ui.control.Button
        EditPointButton           matlab.ui.control.Button
        BenignTable               matlab.ui.control.Table
        ExitButton                matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        DeletePointButton         matlab.ui.control.Button
        PointMap                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainApp % Description
        environment %class that has all environment info
        %window for making objects
        objectMaker
        %window for editing objects
        objectEditor
    end
    methods (Access = public)
        function addPoint(app, selectedType, params)
            % Determine the class type and properties to prompt
            if strcmp(selectedType, 'Benign Object')
                object = BenignObject(params);
                app.environment.benign_objects(end + 1) = object;
            elseif strcmp(selectedType, 'Emission Object')
                object = EmissionObject(params);
                app.environment.emission_objects(end + 1) = object;
            end
            app.updateApp();
        end
        function editPoint(app, selectedType, index, params)
            % Determine the class type and properties to prompt
            if strcmp(selectedType, 'Benign Object')
                object = BenignObject(params);
                app.environment.benign_objects(index) = object;
            elseif strcmp(selectedType, 'Emission Object')
                object = EmissionObject(params);
                app.environment.emission_objects(index) = object;
            end
            app.updateApp(); 
        end
    end
    methods (Access = private)

        function updateApp(app)
            % repopulates the table
            app.populateTable();
            selectedValue = app.PointTypeDropDown.Value;
            app.updateDropDown(selectedValue);
            app.updateMap();
        end
        function populateTable(app)
            app.populateBenignTable();
            app.populateEmissionTable();
        end
        function populateBenignTable(app)
            % Initialize an empty cell array to store table data
            tableData = {};
            
            % Initialize a counter for point numbering
            pointNumber = 1;
            
            % Get benign objects from the environment
            for i = 1:length(app.environment.benign_objects)
                benignObj = app.environment.benign_objects(i);
                % Convert Position and Velocity arrays to strings
                positionStr = mat2str(benignObj.position);  % Convert position to string
                velocityStr = mat2str(benignObj.velocity);  % Convert velocity to string
                
                % Store data in the format: {Number, Type, Position, Velocity}
                tableData{pointNumber, 1} = pointNumber;              % Number
                tableData{pointNumber, 2} = positionStr;              % Position (as string)
                tableData{pointNumber, 3} = velocityStr;              % Velocity (as string)
                pointNumber = pointNumber + 1;
            end
            
            % Update the PointsTable with the collected data
            app.BenignTable.Data = tableData;
        end
        function populateEmissionTable(app)
                        % Initialize an empty cell array to store table data
            tableData = {};
            
            % Initialize a counter for point numbering
            pointNumber = 1;
            
            % Get emission objects from the environment
            for i = 1:length(app.environment.emission_objects)
                emissionObj = app.environment.emission_objects(i);
                % Convert Position and Velocity arrays to strings
                positionStr = mat2str(emissionObj.position);          % Convert position to string
                velocityStr = mat2str(emissionObj.velocity);          % Convert velocity to string
                
                % Store data in the format: {Number, Type, Position, Velocity}
                tableData{pointNumber, 1} = pointNumber;              % Number
                tableData{pointNumber, 2} = positionStr;              % Position (as string)
                tableData{pointNumber, 3} = velocityStr;              % Velocity (as string)
                pointNumber = pointNumber + 1;
            end
            
            % Update the PointsTable with the collected data
            app.EmissionTable.Data = tableData;
        end

    function confirmAddPoint(dlg, app, selectedType, propNames, inputFields)
        % Gather input values and create the point
        params = struct();
        for i = 1:length(propNames)
            params.(propNames{i}) = str2num(inputFields{i}.String); % Convert to number if numeric
            if isempty(params.(propNames{i}))
                params.(propNames{i}) = inputFields{i}.String; % Keep as string if not numeric
            end
        end
        
        % Create and add the point to the environment
        if strcmp(selectedType, 'Benign Object')
            newObj = BenignObject(params);
            app.environment.benign_objects(end + 1) = newObj;
            app.populateBenignTable(); % Refresh BenignTable
        elseif strcmp(selectedType, 'Emission Object')
            newObj = EmissionObject(params);
            app.environment.emission_objects(end + 1) = newObj;
            app.populateEmissionTable(); % Refresh EmissionTable
        end
        
        % Close the dialog
        delete(dlg);
    end

    function updateDropDown(app, selectedType)
        tableEmpty = false();
        if strcmp(selectedType, 'Benign Object')
        % Assuming benignTable contains numeric points in the first column
            if(isempty(app.BenignTable.Data))
                tableEmpty = true();
            else
                pointList = app.BenignTable.Data(:, 1);
            end
        else
            % Assuming emissionTable contains numeric points in the first column
            if(isempty(app.EmissionTable.Data))
               tableEmpty = true();
            else
                pointList = app.EmissionTable.Data(:, 1);
            end
        end
        if (tableEmpty)
            % Set the dropdown to a single blank option if the list is empty
            list = "";
            app.SelectPointDropDown.Items = list;
        else
            % Transpose the point list, convert numeric values to strings, and add a blank option
%            pointListStr = cellstr(num2str(pointList'));  % Convert numbers to strings in cell array format
           %transpose this thang
            pointList = pointList';  
            pointListStr = cellfun(@num2str, pointList, 'UniformOutput', false);
            pointListStr = ["", pointListStr];
            % Convert pointList to a cell array of strings for the dropdown items
            app.SelectPointDropDown.Items = pointListStr;
            end
    end
    function updateFloor(app, isChecked, value)
        app.floorBox.Enable = isChecked;
        if(app.floorBox.Enable == 1)
            app.floorBox.Value = value;
        else
            app.environment.floor = NaN;
            app.floorBox.Value = 0;
        end
        app.updateMap();
    end
    function updateMap(app)
        % Clear the current map
        cla(app.PointMap)
        
        % Plot the center radar point as a black dot
        plot3(app.PointMap, 0, 0, 0, 'ko', 'MarkerSize', 8, 'MarkerFaceColor', 'k');
        hold(app.PointMap, 'on');
        
        % Plot the floor plane if it's not NaN
        if ~isnan(app.environment.floor)
            % Define the floor plane coordinates
            floorZ = app.environment.floor; % Z-height for the floor
            xRange = [-100, 100]; % Define the X-axis range of the floor
            yRange = [-100, 100]; % Define the Y-axis range of the floor
            
            % Create a meshgrid for the plane
            [X, Y] = meshgrid(xRange, yRange);
            Z = floorZ * ones(size(X)); % Set Z to the floor level
            
            % Plot the floor plane with transparency
            surf(app.PointMap, X, Y, Z, 'FaceColor', [0.8, 0.8, 0.8], 'FaceAlpha', 0.3, 'EdgeColor', 'none');
        end

        % Loop through benign objects and plot them as blue dots
        for i = 1:length(app.environment.benign_objects)
            benignObj = app.environment.benign_objects(i);
            pos = benignObj.position;
            % Plot blue dot for benign object
            plot3(app.PointMap, pos(1), pos(2), pos(3), 'bo', 'MarkerSize', 6, 'MarkerFaceColor', 'b');
            % Add text for height
            text(app.PointMap, pos(1), pos(2), pos(3), sprintf('%.2f', pos(3)), 'Color', 'blue', 'VerticalAlignment', 'bottom');
        end
        
        % Loop through emission objects and plot them as red dots
        for i = 1:length(app.environment.emission_objects)
            emissionObj = app.environment.emission_objects(i);
            pos = emissionObj.position;
            % Plot red dot for emission object
            plot3(app.PointMap, pos(1), pos(2), pos(3), 'ro', 'MarkerSize', 6, 'MarkerFaceColor', 'r');
            % Add text for height
            text(app.PointMap, pos(1), pos(2), pos(3), sprintf('%.2f', pos(3)), 'Color', 'red', 'VerticalAlignment', 'bottom');
        end
        
        % Set the view for better visibility of 3D points
        grid(app.PointMap, 'on');
        xlabel(app.PointMap, 'X');
        ylabel(app.PointMap, 'Y');
        zlabel(app.PointMap, 'Z');
        title(app.PointMap, 'Radar Object Map');
        
        hold(app.PointMap, 'off');
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, environment)
               app.mainApp = mainApp;
               app.environment = environment;
               app.populateTable();
               selectValue = app.PointTypeDropDown.Value;
               app.updateDropDown(selectValue);
               app.updateMap();
               if(isnan(app.environment.floor))
                   app.IncludeFloorCheckBox.Value = 0;
                   value = 0;
               else
                   app.IncludeFloorCheckBox.Value = 1;
                   value = app.environment.floor;
               end
               floor = app.IncludeFloorCheckBox.Value;
               app.updateFloor(floor, value);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Enable the button in main app, if the app is
            % still open
            if isvalid(app.mainApp)
                app.mainApp.EditEnvironmentButton.Enable = "on";
            end
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: AddPointButton
        function AddPointButtonPushed(app, event)
            % Get the selected point type
            selectedType = app.PointTypeDropDown.Value;
            
            % Determine the class type and properties to prompt
            if strcmp(selectedType, 'Benign Object')
                propNames = properties(app.environment.benign_objects);
            elseif strcmp(selectedType, 'Emission Object')
                propNames = properties(app.environment.emission_objects);
            else
                return;
            end

            % Open the options dialog and pass inputs
            radarParams = app.mainApp.returnPrimaryRadar();
            app.objectMaker = ObjectMaker(app, selectedType, propNames, radarParams);
            
        end

        % Value changed function: PointTypeDropDown
        function PointTypeDropDownValueChanged(app, event)
            selectedType = app.PointTypeDropDown.Value;
            app.updateDropDown(selectedType); 
        end

        % Button pushed function: DeletePointButton
        function DeletePointButtonPushed(app, event)
            % Get the selected point type and point number
            selectedType = app.PointTypeDropDown.Value;
            selectedPoint = str2double(app.SelectPointDropDown.Value);
            if isnan(selectedPoint)
                uialert(app.UIFigure, 'Please select a valid point.', 'Error');
                return;
            % Perform deletion based on the selected type
            elseif strcmp(selectedType, 'Benign Object')
                if selectedPoint <= length(app.environment.benign_objects)
                    % Delete the benign object from the environment
                    app.environment.benign_objects(selectedPoint) = [];
                end
            elseif strcmp(selectedType, 'Emission Object')
                if selectedPoint <= length(app.environment.emission_objects)
                    % Delete the emission object from the environment
                    app.environment.emission_objects(selectedPoint) = [];
                end
            end
            app.updateDropDown(selectedType);
            
            % Update the app to reflect changes
            app.updateApp();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            updateEnvironment(app.mainApp, app.environment);
        end

        % Button pushed function: EditPointButton
        function EditPointButtonPushed(app, event)
            selectedType = app.PointTypeDropDown.Value;
            selectedPoint = str2double(app.SelectPointDropDown.Value);
            if isnan(selectedPoint)
                uialert(app.UIFigure, 'Please select a valid point.', 'Error');
                return;
            % Determine the class type and properties to prompt
            elseif strcmp(selectedType, 'Benign Object')
                propNames = properties(app.environment.benign_objects);
                editObject = app.environment.benign_objects(selectedPoint).saveObject();
            elseif strcmp(selectedType, 'Emission Object')
                propNames = properties(app.environment.emission_objects);
                editObject = app.environment.emission_objects(selectedPoint).saveObject();
            else
                return;
            end

            % Open the options dialog and pass inputs
            radarParams = app.mainApp.returnPrimaryRadar();
            %object maker actually needs all the params from the selectedObject 
            app.objectMaker = ObjectEditor(app, selectedType, editObject, radarParams, selectedPoint);
        end

        % Value changed function: IncludeFloorCheckBox
        function IncludeFloorCheckBoxValueChanged(app, event)
            value = app.IncludeFloorCheckBox.Value;
            app.updateFloor(value, 0);
        end

        % Value changed function: floorBox
        function floorBoxValueChanged(app, event)
            value = app.floorBox.Value;
            if(app.IncludeFloorCheckBox.Value == 1)
                app.environment.floor = value;
            else
                app.environment.floor = NaN;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PointMap = uiaxes(app.UIFigure);
            title(app.PointMap, 'Title')
            xlabel(app.PointMap, 'X')
            ylabel(app.PointMap, 'Y')
            zlabel(app.PointMap, 'Z')
            app.DeletePointButton = uibutton(app.UIFigure, 'push');
            app.DeletePointButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePointButtonPushed, true);
            app.DeletePointButton.Text = 'Delete Point';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.BenignTable = uitable(app.UIFigure);
            app.BenignTable.ColumnName = {'Num'; 'Position'; 'Velocity'};
            app.BenignTable.RowName = {};
            app.BenignTable.ColumnEditable = [false false true true false false];
            app.EditPointButton = uibutton(app.UIFigure, 'push');
            app.EditPointButton.ButtonPushedFcn = createCallbackFcn(app, @EditPointButtonPushed, true);
            app.EditPointButton.Text = 'Edit Point';
            app.AddPointButton = uibutton(app.UIFigure, 'push');
            app.AddPointButton.ButtonPushedFcn = createCallbackFcn(app, @AddPointButtonPushed, true);
            app.AddPointButton.Text = 'Add Point';
            app.SelectPointDropDownLabel = uilabel(app.UIFigure);
            app.SelectPointDropDownLabel.Text = 'Select Point';
            app.SelectPointDropDown = uidropdown(app.UIFigure);
            app.SelectPointDropDown.Items = {'', '1', '2'};
            app.SelectPointDropDown.Value = '';
            app.PointTypeDropDownLabel = uilabel(app.UIFigure);
            app.PointTypeDropDownLabel.Text = 'Point Type';
            app.PointTypeDropDown = uidropdown(app.UIFigure);
            app.PointTypeDropDown.Items = {'Benign Object', 'Emission Object'};
            app.PointTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PointTypeDropDownValueChanged, true);
            app.PointTypeDropDown.Value = 'Benign Object';
            app.EmissionTable = uitable(app.UIFigure);
            app.EmissionTable.ColumnName = {'Num'; 'Position'; 'Velocity'};
            app.EmissionTable.RowName = {};
            app.BenignObjectsLabel = uilabel(app.UIFigure);
            app.BenignObjectsLabel.Text = 'Benign Objects';
            app.EmissionObjectsLabel = uilabel(app.UIFigure);
            app.EmissionObjectsLabel.Text = 'Emission Objects';
            app.IncludeFloorCheckBox = uicheckbox(app.UIFigure);
            app.IncludeFloorCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeFloorCheckBoxValueChanged, true);
            app.IncludeFloorCheckBox.Text = 'Include Floor';
            app.floorBox = uieditfield(app.UIFigure, 'numeric');
            app.floorBox.ValueChangedFcn = createCallbackFcn(app, @floorBoxValueChanged, true);
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EnvironmentEditor(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end