classdef ObjectEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        NoteWheninputtingvelocityandpositionuseformxyzLabel  matlab.ui.control.Label
        AddLabel           matlab.ui.control.Label
        CopyPrimaryButton  matlab.ui.control.Button
        CancelButton       matlab.ui.control.Button
        AddButton          matlab.ui.control.Button
        UITable            matlab.ui.control.Table
    end

    
    properties (Access = private)
        envEditor
        params
        selectedType
        radarParams
        index
    end
    
    methods (Access = private)
        
    function dataStruct = getTableDataAsStruct(app)
            % Get the data from the table
            tableData = app.UITable.Data;
            
            % Initialize an empty struct
            dataStruct = struct();
            
            % Populate the struct with the first column as field names and
            % the second column as values
            for i = 1:size(tableData, 1)
                fieldName = tableData{i, 1};  % First column contains field names
                fieldValue = tableData{i, 2}; % Second column contains values
                
                % Check if the field name is valid
                if isvarname(fieldName)
                    dataStruct.(fieldName) = fieldValue;
                else
                    warning('Invalid field name: %s. Skipping this entry.', fieldName);
                end
            end
        end
function parsedParams = parseParams(app)
    % Initialize an empty struct to store parsed parameters
    parsedParams = struct();
    
    % Get all field names in app.params
    fieldNames = fieldnames(app.params);
    
    for i = 1:length(fieldNames)
        fieldName = fieldNames{i};
        fieldValue = app.params.(fieldName);
        
        % Check if the field value is a string
        if ischar(fieldValue)
            % Check if it's a single numeric value in string format
            if ~isempty(regexp(fieldValue, '^\d+(\.\d+)?$', 'once'))
                % Convert the single numeric string to a number
                numericValue = str2double(fieldValue);
                parsedParams.(fieldName) = numericValue;
                
            % Check if it contains matrix-like data
            elseif contains(fieldValue, '[')
                % Remove brackets and split by semicolons and spaces
                numericValue = str2num(fieldValue); %#ok<ST2NM> 
                
                if numel(numericValue) == 2
                    % If it's a 2-element vector, convert to [x; y]
                    parsedParams.(fieldName) = [numericValue(1); numericValue(2)];
                elseif numel(numericValue) == 3
                    % If it's a 3-element vector, convert to [x; y; z]
                    parsedParams.(fieldName) = [numericValue(1); numericValue(2); numericValue(3)];
                else
                    % Just store as a matrix if it has different dimensions
                    parsedParams.(fieldName) = numericValue;
                end
            else
                warning('Field %s could not be parsed as numeric.', fieldName);
            end
            
        elseif isnumeric(fieldValue)
            % If it's already numeric, just copy it
            parsedParams.(fieldName) = fieldValue;
        else
            warning('Field %s could not be parsed as numeric.', fieldName);
        end
    end
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, envEditor, selectedType, object, radarParams, index)
            app.index = index;
            % Update the visibility of the CopyPrimaryButton based on selectedType
            if strcmp(selectedType, "Emission Object")
                app.CopyPrimaryButton.Visible = 'on';
            else
                app.CopyPrimaryButton.Visible = 'off';
            end
    
            % Initialize app properties
            app.envEditor = envEditor;
            app.radarParams = radarParams;
            app.selectedType = selectedType;
            app.AddLabel.Text = "Add " + app.selectedType;
    
            % Define the initial table data using the fields from `object`
            fieldNames = fieldnames(object); % Get the field names from `object`
            numRows = length(fieldNames); % Number of rows based on field names length
            numCols = 2; % Two columns: one for names, one for values
    
            % Initialize the table data with field names in the first column and values in the second column
            tableData = cell(numRows, numCols);
            for i = 1:numRows
                tableData{i, 1} = fieldNames{i}; % Field name in the first column
    
                % Convert field value to a string if it's not scalar
                fieldValue = object.(fieldNames{i});
                if isnumeric(fieldValue) && isscalar(fieldValue)
                    tableData{i, 2} = fieldValue; % Keep numeric scalar as is
                else
                    % Convert non-scalar or non-numeric values to string
                    tableData{i, 2} = mat2str(fieldValue); 
                end
            end
    
            % Set the UITable data to the populated tableData
            app.UITable.Data = tableData;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            %get the parameters from the table
            app.params = app.getTableDataAsStruct();
            app.params = app.parseParams();
            %add the point to the environment
            editPoint(app.envEditor, app.selectedType, app.index, app.params);
            %close the app
            delete(app)
        end

        % Button pushed function: CopyPrimaryButton
        function CopyPrimaryButtonPushed(app, event)
            % Get radarParams field names and values
            radarFieldNames = fieldnames(app.radarParams);
            radarFieldValues = struct2cell(app.radarParams);
            
            % Loop through each row in the UITable
            for i = 1:size(app.UITable.Data, 1)
                % Get the field name in the first column of the current row
                tableFieldName = app.UITable.Data{i, 1};
                
                % Check if this field name exists in radarParams
                fieldIndex = find(strcmp(radarFieldNames, tableFieldName), 1);
                
                if ~isempty(fieldIndex)
                    % If the field exists in radarParams, fill in the value
                    app.UITable.Data{i, 2} = radarFieldValues{fieldIndex};
                end
                % If the field doesn't exist in radarParams, do nothing (leave current value as is)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CopyPrimaryButton = uibutton(app.UIFigure, 'push');
            app.CopyPrimaryButton.ButtonPushedFcn = createCallbackFcn(app, @CopyPrimaryButtonPushed, true);
            app.CopyPrimaryButton.Text = 'Copy Primary';
            app.AddLabel = uilabel(app.UIFigure);
            app.AddLabel.Text = 'Add';
            app.NoteWheninputtingvelocityandpositionuseformxyzLabel = uilabel(app.UIFigure);
            app.NoteWheninputtingvelocityandpositionuseformxyzLabel.Text = 'Note: When inputting velocity and position use form [x;y;z]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ObjectEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end