classdef RadarEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        SaveButton  matlab.ui.control.Button
        ExitButton  matlab.ui.control.Button
        radarTable  matlab.ui.control.Table
        radarGraph  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainApp % Description
        radar %class that has all radar info
    end
    
    methods (Access = private)    
        % Function to populate radar properties into the table
        function populateRadarTable(app)
            % Get the meta-class object for the Radar class
            radarMeta = metaclass(app.radar);
            % Initialize a cell to hold the property data
            data = {};
            
            % Iterate through all the properties in the meta-class
            for prop = radarMeta.PropertyList'
                % Only add public properties
                if strcmp(prop.SetAccess, 'public')
                    propName = prop.Name;
                    propValue = app.radar.(propName);
                    
                    % Check if the property is an array
                    if isnumeric(propValue) && numel(propValue) > 1
                        % Convert the array to a string
                        valueStr = mat2str(propValue);
                    else
                        % Use the value as-is if it's a scalar or non-array
                        valueStr = propValue;
                    end
                    
                    % Add property name and value to the data cell array
                    data{end+1, 1} = propName;   % Property name
                    data{end, 2} = valueStr; % Property value (as a string)
                end
            end
            
            % Set the data to the radarTable
            app.radarTable.Data = data;
        end

        function plotRadarGraph(app)
            t = (0:1/app.radar.fs:app.radar.t_max-1/app.radar.fs);
            plot(app.radarGraph, t, real(app.radar.tx_waveform()));
            title(app.radarGraph, 'Radar FMCW Signal Waveform');
            xlabel(app.radarGraph, 'Time (s)');
            ylabel(app.radarGraph, 'Amplitude (v)');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, radar)
               app.mainApp = mainApp;
               app.radar = radar;
               app.populateRadarTable(); % Populate table on startup
               app.plotRadarGraph();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Retrieve data from the table
            tableData = app.radarTable.Data;

            % Update radar object with new values
            for i = 1:size(tableData, 1)
                propName = tableData{i, 1};
                newValue = tableData{i, 2};
                try
                    % Update the property if it's editable and the value is valid
                    app.radar.(propName) = newValue;
                catch
                    % Ignore properties that cannot be updated or invalid values
                    warning('Could not update property %s with value %s', propName, newValue);
                end
            end

            % Confirm save
            updateRadar(app.mainApp,app.radar)
            app.plotRadarGraph();
            uialert(app.UIFigure, 'Radar settings saved successfully!', 'Save Confirmation');
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Enable the button in main app, if the app is
            % still open
            if isvalid(app.mainApp)
                app.mainApp.EditRadarButton.Enable = "on";
            end
            
            % Delete the dialog box 
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.radarGraph = uiaxes(app.UIFigure);
            title(app.radarGraph, 'Radar Waveform')
            xlabel(app.radarGraph, 'X')
            ylabel(app.radarGraph, 'Y')
            zlabel(app.radarGraph, 'Z')
            app.radarTable = uitable(app.UIFigure);
            app.radarTable.ColumnName = {'Value'; 'Input'};
            app.radarTable.RowName = {};
            app.radarTable.ColumnEditable = [false true];
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RadarEditor(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end